classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel_2                     matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        ModeofchangePanel           matlab.ui.container.Panel
        ModeofvisualizationPanel    matlab.ui.container.Panel
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Option3Button               matlab.ui.control.ToggleButton
        TimeLapseButton             matlab.ui.control.ToggleButton
        DifferenceHighlightsButton  matlab.ui.control.ToggleButton
        LoadLocationButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadLocationButton
        function LoadLocation(app, event)
                
            % Load all chosen images into FileNames, load path to PathName
            [FileNames,PathName] = uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select your Images (Two minimum)','MultiSelect','on');
            
            % If only one image chosen, selection will be repeated until
            % min two images
            while (isa(FileNames,'char'))
                [FileNames,PathName] = uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select your Images (Two minimum)','MultiSelect','on');
            end
                  
            % Get number of chosen images
            nr_files = length(FileNames);
            
            % Create cell array with size of number of files
            image_array = {nr_files};
            
            % Fill cell array with images
            for i = 1:nr_files
                
                % Create whole file name: file path + file name
                file = fullfile(PathName,FileNames{i});
                
                % Read image from file
                image = imread(file);
                
                % Store image in cell array
                image_array{i} = image;
            end
            
            % Enable start button, disable stop button
            app.StartButton.Enable = true;
            app.StopButton.Enable = false;
            
            % Display how many images were loaded into array and make label
            % visible
            app.Label.Text = sprintf('Number of loaded images: %f', numel(image_array));
            app.Label.Visible = true;
            
            % Visialize first image in array
            %imshow(image_array{1},'Parent',app.UIAxes);
            %app.UIAxes.Visible = true;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Enable or disable buttons
            app.LoadLocationButton.Enable = false;
            app.StartButton.Enable = false;
            app.StopButton.Enable = true;
            
            [im1,im2] = rectify2(image_array{1}, image_array{2});
            imshowpair(I1_cropped,I2_cropped,'montage');
            
            %% TODO: Start function and enable visualization
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Enable or disable buttons
            app.LoadLocationButton.Enable = true;
            app.StartButton.Enable = true;
            app.StopButton.Enable = false;
            
            %% TODO: Stop function
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.LoadLocationButton = uibutton(app.GridLayout2, 'push');
            app.LoadLocationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLocation, true);
            app.LoadLocationButton.Text = {'Load Location'; ''};
            app.ModeofvisualizationPanel = uipanel(app.GridLayout2);
            app.ModeofvisualizationPanel.Title = 'Mode of visualization';
            app.ButtonGroup = uibuttongroup(app.ModeofvisualizationPanel);
            app.DifferenceHighlightsButton = uitogglebutton(app.ButtonGroup);
            app.DifferenceHighlightsButton.Text = {'Difference-Highlights'; ''};
            app.DifferenceHighlightsButton.Value = true;
            app.TimeLapseButton = uitogglebutton(app.ButtonGroup);
            app.TimeLapseButton.Text = {'Time-Lapse'; ''};
            app.Option3Button = uitogglebutton(app.ButtonGroup);
            app.Option3Button.Text = 'Option 3';
            app.ModeofchangePanel = uipanel(app.GridLayout2);
            app.ModeofchangePanel.Title = 'Mode of change';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.Panel_2 = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.Panel_2);
            app.Label.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end